classdef PartC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        XAXISLabel                matlab.ui.control.Label
        Button_4                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_1                  matlab.ui.control.Button
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        CONNECTIONLabel           matlab.ui.control.Label
        PortNumberEditField       matlab.ui.control.EditField
        PortNumberEditFieldLabel  matlab.ui.control.Label
        ResettoDefaultButton      matlab.ui.control.Button
        ConfirmChangesButton      matlab.ui.control.Button
        SETTIMEDELAYSLabel        matlab.ui.control.Label
        TimeDelayColumnsLabel     matlab.ui.control.Label
        TimeDelayRowsLabel        matlab.ui.control.Label
        TimeDelayTable            matlab.ui.control.Table
        ImageGripperRetracted     matlab.ui.control.Image
        ImageRobotBackground      matlab.ui.control.Image
    end

    
    properties (Access = private)
        robot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.robot=PlateLoaderSim(4);
            myTime=[0 60 20 30 0
            0 0 30 30 0
            0 30 0 30 0
            0 30 30 0 0
            0 30 20 60 0];
            app.TimeDelayTable.Data=myTime;
            app.ImageGripperRetracted.Position=[393,481,100,100];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
           app.robot=PlateLoader(app.PortNumberEditField.Value-48);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.robot=PlateLoaderSim(4);
        end

        % Button pushed function: ResettoDefaultButton
        function ResettoDefaultButtonPushed(app, event)
            app.robot.resetDefaultTimes
            app.TimeDelayTable.Data=[0 60 20 30 0
                0 0 30 30 0
                0 30 0 30 0
                0 30 30 0 0
                0 30 20 60 0];
        end

        % Button pushed function: ConfirmChangesButton
        function ConfirmChangesButtonPushed(app, event)
            app.robot.setTimeValues(app.TimeDelayTable.Data);
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.ImageGripperRetracted.Position=[186,481,100,100];
            app.robot.x(1)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.ImageGripperRetracted.Position=[289,481,100,100];
            app.robot.x(2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ImageGripperRetracted.Position=[393,481,100,100];
            app.robot.x(3)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.ImageGripperRetracted.Position=[494,481,100,100];
            app.robot.x(4)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.ImageGripperRetracted.Position=[595,481,100,100];
            app.robot.x(5)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageRobotBackground = uiimage(app.UIFigure);
            app.ImageRobotBackground.ImageSource = fullfile(pathToMLAPP, 'robot_background.jpg');
            app.ImageGripperRetracted = uiimage(app.UIFigure);
            app.ImageGripperRetracted.ImageSource = fullfile(pathToMLAPP, 'gripper_closed_no_plate.jpg');
            app.TimeDelayTable = uitable(app.UIFigure);
            app.TimeDelayTable.ColumnName = '';
            app.TimeDelayTable.RowName = {};
            app.TimeDelayTable.ColumnEditable = true;
            app.TimeDelayRowsLabel = uilabel(app.UIFigure);
            app.TimeDelayRowsLabel.Text = {'1'; '2'; '3'; '4'; '5'};
            app.TimeDelayColumnsLabel = uilabel(app.UIFigure);
            app.TimeDelayColumnsLabel.Text = '  1    2    3    4    5';
            app.SETTIMEDELAYSLabel = uilabel(app.UIFigure);
            app.SETTIMEDELAYSLabel.Text = 'SET TIME DELAYS';
            app.ConfirmChangesButton = uibutton(app.UIFigure, 'push');
            app.ConfirmChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmChangesButtonPushed, true);
            app.ConfirmChangesButton.Text = 'Confirm Changes';
            app.ResettoDefaultButton = uibutton(app.UIFigure, 'push');
            app.ResettoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultButtonPushed, true);
            app.ResettoDefaultButton.Text = 'Reset to Default';
            app.PortNumberEditFieldLabel = uilabel(app.UIFigure);
            app.PortNumberEditFieldLabel.Text = 'Port Number';
            app.PortNumberEditField = uieditfield(app.UIFigure, 'text');
            app.PortNumberEditField.Value = '4';
            app.CONNECTIONLabel = uilabel(app.UIFigure);
            app.CONNECTIONLabel.Text = 'CONNECTION';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.XAXISLabel = uilabel(app.UIFigure);
            app.XAXISLabel.Text = 'X-AXIS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PartC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end